classdef Traitement_images < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        Label9_12                     matlab.ui.control.Label
        Label9_11                     matlab.ui.control.Label
        Label9_10                     matlab.ui.control.Label
        Label9_9                      matlab.ui.control.Label
        PasswordEditField_3Label      matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        UsernameEditField_3Label      matlab.ui.control.Label
        UsernameEditField             matlab.ui.control.EditField
        Image2_3                      matlab.ui.control.Image
        PleaselogintoaccesstheapplicationandutilizeitsfeaturesLabel_3  matlab.ui.control.Label
        RueduMoulinNeuf80000AmiensLabel_3  matlab.ui.control.Label
        LocationLabel_3               matlab.ui.control.Label
        RemotLabImageProcessingLabel  matlab.ui.control.Label
        LoginButton_3                 matlab.ui.control.Button
        Hyperlink_3                   matlab.ui.control.Hyperlink
        NewtoRemotLabLabel_3          matlab.ui.control.Label
        ObjectivesLabel_3             matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        BallLevitationDashboardLabel  matlab.ui.control.Label
        HTML                          matlab.ui.control.HTML
        RemotLabLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        % User login definitions
        IsLoggedIn            logical = false;   % Login status
        SessionUsername       char;              % Login Username
        SessionUserId         char;              % ID of the logged in user
    end

    methods (Access = private)
        % Description:
        % This function displays the main interface of the application upon a successful login.
        % It makes the main interface panel visible while hiding the login form components.
        % Additionally, it updates the status label with a welcome message, including the
        % username and user ID of the logged-in user.
        function showMainInterface(app)
            % If login is successful, show the main interface and hide the login form.
            app.HTML.Visible = 'on';  % Make #app.panel interface visible
            app.StatusLabel.Text = ['Welcome, ' app.SessionUsername ' (ID: ' app.SessionUserId ')'];
            app.Panel.Visible = 'off';  % Hide login form ($app.panel2)
            % Lance l'app Streamlit (dans un terminal en arrière-plan)
            try
                % Commande à exécuter : activer le venv + lancer Streamlit
                cmd = 'start cmd.exe /k "C:\Users\Remotlab\Bone-Fracture-Detection\venv-tf\Scripts\activate && streamlit run C:\Users\Remotlab\Bone-Fracture-Detection\app.py"';

                % Lancer en arrière-plan (le & marche parfois mal sous Windows, donc on évite ici)
                system(cmd);

                pause(3);  % Attendre que Streamlit démarre

                % Afficher Streamlit dans le composant HTML intégré
                htmlPath = fullfile(pwd, 'streamlit_embed.html');
                app.HTML.HTMLSource = htmlPath;


            catch err
                app.StatusLabel.Text = ['Erreur Streamlit : ' err.message];
            end

        end 
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide #app.panel on startup
            app.HTML.Visible = 'off';
        end

        % Button pushed function: LoginButton_3
        function LoginButton_3Pushed(app, event)
            % Convert username and password to JSON format
            username = app.UsernameEditField.Value;
            password = app.PasswordEditField.Value;
            
            % Blank check
            if isempty(username) || isempty(password)
                app.StatusLabel.Text = 'Username or password is empty.';
                return;
            end
            
            % Prepare data in JSON format
            data = jsonencode(struct('username', username, 'password', password));
        
            try
                % Send JSON request to PHP script
                options = weboptions('ContentType', 'text', 'RequestMethod', 'post');  % Use 'text' as ContentType
                response = webwrite('http://remotlab.com/login_app.php', data, options);
        
                % Yanıtı çözümle
                responseData = jsondecode(response);
                
                % Yanıtı kontrol et
                if strcmp(responseData.status, 'success')
                    app.IsLoggedIn = true;
                    app.SessionUsername = username;
                    app.SessionUserId = num2str(responseData.userId);  % Get User ID
                    app.StatusLabel.Text = 'Login successful!';
                    app.showMainInterface();
                else
                    app.StatusLabel.Text = responseData.message; % Show error message
                end
            catch ME
                app.StatusLabel.Text = ['Login failed. Error: ' ME.message];
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RemotLabLabel = uilabel(app.UIFigure);
            app.RemotLabLabel.Text = 'RemotLab';
            app.HTML = uihtml(app.UIFigure);
            app.BallLevitationDashboardLabel = uilabel(app.UIFigure);
            app.BallLevitationDashboardLabel.Text = 'Ball Levitation: Dashboard';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.Panel = uipanel(app.UIFigure);
            app.ObjectivesLabel_3 = uilabel(app.Panel);
            app.ObjectivesLabel_3.Text = 'Objectives:';
            app.NewtoRemotLabLabel_3 = uilabel(app.Panel);
            app.NewtoRemotLabLabel_3.Text = 'New to RemotLab?';
            app.Hyperlink_3 = uihyperlink(app.Panel);
            app.Hyperlink_3.URL = 'https://websfair.net/';
            app.Hyperlink_3.Text = 'Create an account';
            app.LoginButton_3 = uibutton(app.Panel, 'push');
            app.LoginButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_3Pushed, true);
            app.LoginButton_3.Text = 'Login';
            app.RemotLabImageProcessingLabel = uilabel(app.Panel);
            app.RemotLabImageProcessingLabel.Text = 'RemotLab: Image Processing';
            app.LocationLabel_3 = uilabel(app.Panel);
            app.LocationLabel_3.Text = 'Location: ';
            app.RueduMoulinNeuf80000AmiensLabel_3 = uilabel(app.Panel);
            app.RueduMoulinNeuf80000AmiensLabel_3.Text = '7 Rue du Moulin Neuf, 80000 Amiens';
            app.PleaselogintoaccesstheapplicationandutilizeitsfeaturesLabel_3 = uilabel(app.Panel);
            app.PleaselogintoaccesstheapplicationandutilizeitsfeaturesLabel_3.Text = 'Please log in to access the application and utilize its features.';
            app.Image2_3 = uiimage(app.Panel);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'cara-shelton-_GpJpHnyCSw-unsplash.jpg');
            app.UsernameEditField = uieditfield(app.Panel, 'text');
            app.UsernameEditField.Value = 'admin';
            app.UsernameEditField_3Label = uilabel(app.Panel);
            app.UsernameEditField_3Label.Text = 'Username';
            app.PasswordEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditField.Value = '123321';
            app.PasswordEditField_3Label = uilabel(app.Panel);
            app.PasswordEditField_3Label.Text = 'Password';
            app.Label9_9 = uilabel(app.Panel);
            app.Label9_9.Text = 'Optimize Renewable Energy Efficiency: Facilitate the optimization of energy production and thermal efficiency in a hybrid Photovoltaic (PV) and Photovoltaic-Thermal (PVT) system by enabling precise control of operational parameters such as lamp power, fan power, and pump power.';
            app.Label9_10 = uilabel(app.Panel);
            app.Label9_10.Text = 'Enhance User Understanding of Solar Technology: Provide users with an interactive platform to visualize and analyze real-time performance metrics of PV and PVT systems, such as voltage, current, power output, and temperature.';
            app.Label9_11 = uilabel(app.Panel);
            app.Label9_11.Text = 'Promote Practical Experimentation: Empower users to explore the practical relationship between operational parameters and system performance, promoting a hands-on approach to learning renewable energy principles.';
            app.Label9_12 = uilabel(app.Panel);
            app.Label9_12.Text = 'Support Data-Driven Decision Making: Enable data acquisition, analysis, and visualization to support decision-making processes for improving energy management and advancing research in solar energy technologies.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Traitement_images

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end